classdef APPLICATION_FILTRES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LuminanceTab                   matlab.ui.container.Tab
        LuminanceLabel                 matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        LuminanceMoinsButton           matlab.ui.control.Button
        LuminancePlusButton            matlab.ui.control.Button
        RotationTab                    matlab.ui.container.Tab
        AngledeROTATIONEditField_2     matlab.ui.control.NumericEditField
        AngledeROTATIONEditField_2Label  matlab.ui.control.Label
        AppliquerButton                matlab.ui.control.Button
        RotationLabel                  matlab.ui.control.Label
        BruitTab                       matlab.ui.container.Tab
        TESTButton                     matlab.ui.control.Button
        BruitImpulseButton             matlab.ui.control.Button
        BruitButton                    matlab.ui.control.Button
        BruitLabel                     matlab.ui.control.Label
        GrisTab                        matlab.ui.container.Tab
        GreyScaleButton                matlab.ui.control.Button
        FiltreGrisLabel                matlab.ui.control.Label
        ConvolutionTab                 matlab.ui.container.Tab
        SaisirKERNEL33Label            matlab.ui.control.Label
        PSExempledeembossFilerK220270000Label  matlab.ui.control.Label
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        AppliquerButton_2              matlab.ui.control.Button
        ConvolutionLabel               matlab.ui.control.Label
        MiroirTab                      matlab.ui.container.Tab
        MiroirVerticalButton           matlab.ui.control.Button
        MiroirHorizontalButton         matlab.ui.control.Button
        MiroirLabel                    matlab.ui.control.Label
        HistogrammeTab                 matlab.ui.container.Tab
        FiltreLabel                    matlab.ui.control.Label
        HistogrammeEqualizationButton  matlab.ui.control.Button
        PlotsLabel                     matlab.ui.control.Label
        HistogrammeButton              matlab.ui.control.Button
        ErosionTab                     matlab.ui.container.Tab
        AppliquerButton_3              matlab.ui.control.Button
        EROSIONLabel                   matlab.ui.control.Label
        DilationTab                    matlab.ui.container.Tab
        AppliquerButton_4              matlab.ui.control.StateButton
        DILATIONLabel                  matlab.ui.control.Label
        ContourTab                     matlab.ui.container.Tab
        ContoursLabel                  matlab.ui.control.Label
        PSRobertmarcherapidementLabel  matlab.ui.control.Label
        RobertButton                   matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        FiltresTab                     matlab.ui.container.Tab
        FiltresButtonGroup             matlab.ui.container.ButtonGroup
        MoyenneButton                  matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        MedianButton                   matlab.ui.control.Button
        AutresTab                      matlab.ui.container.Tab
        Spinner_4                      matlab.ui.control.Spinner
        Spinner_3                      matlab.ui.control.Spinner
        Spinner_2                      matlab.ui.control.Spinner
        Spinner                        matlab.ui.control.Spinner
        BaseButton                     matlab.ui.control.Button
        AutresLabel                    matlab.ui.control.Label
        SeuillageButton                matlab.ui.control.Button
        LUTButton                      matlab.ui.control.Button
        ResizeButton                   matlab.ui.control.Button
        EnregistrerlimageButton        matlab.ui.control.Button
        ChoisirlimageButton            matlab.ui.control.Button
        PROJETTRAITEMENTDIMAGESLabel   matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);

            imshow(a,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            %             imshow(a,'Parent',app.UIAxes2);           
            %             axis(app.UIAxes2, 'tight');
            % app.Lamp.Color='green';
            %app.autorise.Text='Ajouter des filtres';
        end

        % Button pushed function: ChoisirlimageButton
        function ChoisirlimageButtonPushed(app, event)
            global a;
            %[filename, pathname] = uigetfile('*.*', 'Pick an Image');
            %filename=strcat(pathname,filename);
            % a=imread(filename);
            a=imread('ship.jpg');
            imshow(a,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            %             imshow(a,'Parent',app.UIAxes2);
            %             axis(app.UIAxes2, 'tight');
            %  app.Lamp.Color='green';
            %   app.autorise.Text='Ajouter des filtres';
        end

        % Button pushed function: EnregistrerlimageButton
        function EnregistrerlimageButtonPushed(app, event)
            %im=getimage(app.UIAxes2); undefined function
            % im=findobj(app.UIAxes2, 'type', 'image'); %ca marche mais type
            % Image et il veut pas convertir
            %im=findall(app.UIAxes2, 'type', 'image');
            %im=imhandles(app.UIAxes2); undefined
            %              im1 = double(im);
            %              I = ind2rgb(im1,Mcolor);
            % im1 = uint8(normalize(im, 'range', [0 255]));
            %imwrite(im, 'F:/output/filename.jpg', 'jpg'));


            axis(app.UIAxes2, 'tight');
            f=getframe(app.UIAxes);
            im1 = f.cdata;
            imwrite(im1,'image_filtre.jpg','jpg');
            imshow('image_filtre.jpg');

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global a;
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)
            global a;

            J=a;
            J =rgb2gray(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: LuminanceMoinsButton
        function LuminanceMoinsButtonPushed(app, event)

            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            global a;
            im1=a;

            R=app.Slider.Value;

            im1 =luminanceMoins(im1,R);
            imshow(im1,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: LuminancePlusButton
        function LuminancePlusButtonPushed(app, event)
            %             f=getframe(app.UIAxes2);
            %             im1 = f.cdata;
            global a;
            im1=a;
            R=app.Slider.Value;

            im1 =luminancePlus(im1,R);
            imshow(im1,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            global a;
            K=rgb2gray(a);
            K =Median(K); %K =FiltreMedian(K ,3);
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            global a;
            K=rgb2gray(a);
            K =Gaussian(K);
            %K =FiltreGaussien(K ,5, 7);
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: MoyenneButton
        function MoyenneButtonPushed(app, event)
            global a;
            K=rgb2gray(a);
            K =Medium(K);%K =FiltreMoyenne(K ,5);
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: MiroirVerticalButton
        function MiroirVerticalButtonPushed(app, event)
            global a;

            J=a;
            J =miroireV(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: MiroirHorizontalButton
        function MiroirHorizontalButtonPushed(app, event)
            global a;

            J=a;
            J =miroireH(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');

        end

        % Button pushed function: HistogrammeButton
        function HistogrammeButtonPushed(app, event)
            global a;
            global H;
            
            H=rgb2gray(a);
            H =histograme(H);
            %             cla(app.UIAxes2, 'reset');
            %             app.UIAxes2.Title.String='Result';
            %             app.UIAxes2.FontName='Algerian';
            %             app.UIAxes2.XColor='0.07,0.62,1.00';
            %             app.UIAxes2.YColor='0.07,0.62,1.00';
            % app.UIAxes2.YColor='0.00,0.45,0.74';
            bar('Parent',app.UIAxes2,H);
        end

        % Button pushed function: HistogrammeEqualizationButton
        function HistogrammeEqualizationButtonPushed(app, event)
            global a;

            J=rgb2gray(a) ;
            J =histogramme_equalization(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Callback function
        function HistogrammeComuleButtonPushed(app, event)
            global a;

            J=rgb2gray(a);
            J =CumultativeHistogramFunction(J);
            imshow(J,'Parent',app.UIAxes2);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            global a;

            J=a;
            R=app.AngledeROTATIONEditField_2.Value;
            J =rotation(J,R);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: BruitButton
        function BruitButtonPushed(app, event)
            global a;

            J=a;
            J =bruit(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: BruitImpulseButton
        function BruitImpulseButtonPushed(app, event)
            global a;

            J=a;
            J =bruitImpulse(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            global a;
            
            J=a;
            K=app.Spinner.Value;
            J =resize(J,K);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: SeuillageButton
        function SeuillageButtonPushed(app, event)

            global a;

            J=a;
            K=app.Spinner_2.Value;
            J =seuillage(J,K);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: LUTButton
        function LUTButtonPushed(app, event)
            global a;

            J=a;
            MIN=app.Spinner_3.Value;
            MAX=app.Spinner_4.Value;
            J =LUT(J,MIN,MAX);

            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: AppliquerButton_2
        function AppliquerButton_2Pushed(app, event)
            global a;

            J=a;
            % K=[-2,-2,0,-2,7,0,0,0,0];
            %J=conv2(J,K,'same');

            a1=app.EditField.Value;a2=app.EditField_2.Value;a3=app.EditField_3.Value;
            a4=app.EditField_4.Value;a5=app.EditField_5.Value;a6=app.EditField_6.Value;
            a7=app.EditField_7.Value;a8=app.EditField_8.Value;a9=app.EditField_7.Value;
            K=[a1,a1,a2,a3,a4,a5,a6,a7,a8,a9];
            J=convn(J,K);

            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global a;

            J=rgb2gray(a);
            J =Prewitt(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
            global a;

            J=a;
            J =Robert(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            global a;

            J=rgb2gray(a);
            J =Sobel(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: BaseButton
        function BaseButtonPushed(app, event)
            global a;

            J=a;
            J =base(J);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            global a;

            J=a;
            J =LookUpTable(J,2,10);
            imshow(J,'Parent',app.UIAxes);
        end

        % Callback function
        function Switch_2ValueChanged(app, event)

            global a;

            %             if app.Switch_2.Value == "Off"
            %                 axis(app.UIAxes2, 'tight');
            %                 f=getframe(app.UIAxes2);
            %                 im1 = f.cdata;
            %             end
            
            axis(app.UIAxes2, 'tight');
            f=getframe(app.UIAxes2);
            im1 = f.cdata;
            value = app.Switch_2.Value;

            switch value
                case 'Off'
                    imshow(im1,'Parent',app.UIAxes2)
                case 'On'
                    imshow(a,'Parent',app.UIAxes2)
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
        end

        % Button pushed function: AppliquerButton_3
        function AppliquerButton_3Pushed(app, event)
            global a;
            st1=[ 1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1]; 

            J= a ; %rgb2gray(a);
            J =erosion(J,st1);
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Value changed function: AppliquerButton_4
        function AppliquerButton_4ValueChanged(app, event)
            global a;
            st1=[ 1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1
                  1 1 1 1 1]; 
            J= a ; %rgb2gray(a);
            J = dilation(J , st1) ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.PROJETTRAITEMENTDIMAGESLabel = uilabel(app.UIFigure);
            app.PROJETTRAITEMENTDIMAGESLabel.Text = 'PROJET TRAITEMENT D''IMAGES';
            app.ChoisirlimageButton = uibutton(app.UIFigure, 'push');
            app.ChoisirlimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoisirlimageButtonPushed, true);
            app.ChoisirlimageButton.Text = {'Choisir'; 'l''image'};
            app.EnregistrerlimageButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerlimageButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlimageButtonPushed, true);
            app.EnregistrerlimageButton.Text = {'Enregistrer'; 'l''image'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LuminanceTab = uitab(app.TabGroup);
            app.LuminanceTab.Title = 'Luminance';
            app.LuminancePlusButton = uibutton(app.LuminanceTab, 'push');
            app.LuminancePlusButton.ButtonPushedFcn = createCallbackFcn(app, @LuminancePlusButtonPushed, true);
            app.LuminancePlusButton.Text = {'Luminance '; 'Plus'};
            app.LuminanceMoinsButton = uibutton(app.LuminanceTab, 'push');
            app.LuminanceMoinsButton.ButtonPushedFcn = createCallbackFcn(app, @LuminanceMoinsButtonPushed, true);
            app.LuminanceMoinsButton.Text = {'Luminance '; 'Moins'};
            app.Slider = uislider(app.LuminanceTab);
            app.Slider.Limits = [0 200];
            app.LuminanceLabel = uilabel(app.LuminanceTab);
            app.LuminanceLabel.Text = 'Luminance';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.RotationLabel = uilabel(app.RotationTab);
            app.RotationLabel.Text = 'Rotation';
            app.AppliquerButton = uibutton(app.RotationTab, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AngledeROTATIONEditField_2Label = uilabel(app.RotationTab);
            app.AngledeROTATIONEditField_2Label.Text = 'Angle de ROTATION :';
            app.AngledeROTATIONEditField_2 = uieditfield(app.RotationTab, 'numeric');
            app.BruitTab = uitab(app.TabGroup);
            app.BruitTab.Title = 'Bruit';
            app.BruitLabel = uilabel(app.BruitTab);
            app.BruitLabel.Text = 'Bruit';
            app.BruitButton = uibutton(app.BruitTab, 'push');
            app.BruitButton.ButtonPushedFcn = createCallbackFcn(app, @BruitButtonPushed, true);
            app.BruitButton.Text = 'Bruit';
            app.BruitImpulseButton = uibutton(app.BruitTab, 'push');
            app.BruitImpulseButton.ButtonPushedFcn = createCallbackFcn(app, @BruitImpulseButtonPushed, true);
            app.BruitImpulseButton.Text = 'Bruit Impulse';
            app.TESTButton = uibutton(app.BruitTab, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.GrisTab = uitab(app.TabGroup);
            app.GrisTab.Title = 'Gris';
            app.FiltreGrisLabel = uilabel(app.GrisTab);
            app.FiltreGrisLabel.Text = 'Filtre Gris';
            app.GreyScaleButton = uibutton(app.GrisTab, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ConvolutionLabel = uilabel(app.ConvolutionTab);
            app.ConvolutionLabel.Text = 'Convolution';
            app.AppliquerButton_2 = uibutton(app.ConvolutionTab, 'push');
            app.AppliquerButton_2.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_2Pushed, true);
            app.AppliquerButton_2.Text = 'Appliquer';
            app.EditField = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField_2 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_3 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_4 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_5 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_6 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_7 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_7.RoundFractionalValues = 'on';
            app.EditField_8 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_8.RoundFractionalValues = 'on';
            app.EditField_9 = uieditfield(app.ConvolutionTab, 'numeric');
            app.EditField_9.RoundFractionalValues = 'on';
            app.PSExempledeembossFilerK220270000Label = uilabel(app.ConvolutionTab);
            app.PSExempledeembossFilerK220270000Label.Text = {'PS: Exemple de embossFiler'; 'K=[-2,-2,0,-2,7,0,0,0,0]'};
            app.SaisirKERNEL33Label = uilabel(app.ConvolutionTab);
            app.SaisirKERNEL33Label.Text = 'Saisir KERNEL 3*3 :';
            app.MiroirTab = uitab(app.TabGroup);
            app.MiroirTab.Title = 'Miroir';
            app.MiroirLabel = uilabel(app.MiroirTab);
            app.MiroirLabel.Text = 'Miroir';
            app.MiroirHorizontalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirHorizontalButtonPushed, true);
            app.MiroirHorizontalButton.Text = 'Miroir Horizontal';
            app.MiroirVerticalButton = uibutton(app.MiroirTab, 'push');
            app.MiroirVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @MiroirVerticalButtonPushed, true);
            app.MiroirVerticalButton.Text = 'Miroir Vertical';
            app.HistogrammeTab = uitab(app.TabGroup);
            app.HistogrammeTab.Title = 'Histogramme';
            app.HistogrammeButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeButtonPushed, true);
            app.HistogrammeButton.Text = 'Histogramme';
            app.PlotsLabel = uilabel(app.HistogrammeTab);
            app.PlotsLabel.Text = 'Plots';
            app.HistogrammeEqualizationButton = uibutton(app.HistogrammeTab, 'push');
            app.HistogrammeEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogrammeEqualizationButtonPushed, true);
            app.HistogrammeEqualizationButton.Text = {'Histogramme'; 'Equalization'};
            app.FiltreLabel = uilabel(app.HistogrammeTab);
            app.FiltreLabel.Text = 'Filtre';
            app.ErosionTab = uitab(app.TabGroup);
            app.ErosionTab.Title = 'Erosion';
            app.EROSIONLabel = uilabel(app.ErosionTab);
            app.EROSIONLabel.Text = 'EROSION ';
            app.AppliquerButton_3 = uibutton(app.ErosionTab, 'push');
            app.AppliquerButton_3.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButton_3Pushed, true);
            app.AppliquerButton_3.Text = 'Appliquer';
            app.DilationTab = uitab(app.TabGroup);
            app.DilationTab.Title = 'Dilation';
            app.DILATIONLabel = uilabel(app.DilationTab);
            app.DILATIONLabel.Text = 'DILATION';
            app.AppliquerButton_4 = uibutton(app.DilationTab, 'state');
            app.AppliquerButton_4.ValueChangedFcn = createCallbackFcn(app, @AppliquerButton_4ValueChanged, true);
            app.AppliquerButton_4.Text = 'Appliquer';
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.PrewittButton = uibutton(app.ContourTab, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.ContourTab, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertButton = uibutton(app.ContourTab, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert';
            app.PSRobertmarcherapidementLabel = uilabel(app.ContourTab);
            app.PSRobertmarcherapidementLabel.Text = 'PS: Robert marche rapidement';
            app.ContoursLabel = uilabel(app.ContourTab);
            app.ContoursLabel.Text = 'Contours';
            app.FiltresTab = uitab(app.TabGroup);
            app.FiltresTab.Title = 'Filtres';
            app.FiltresButtonGroup = uibuttongroup(app.FiltresTab);
            app.FiltresButtonGroup.Title = 'Filtres';
            app.MedianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton = uibutton(app.FiltresButtonGroup, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.MoyenneButton = uibutton(app.FiltresButtonGroup, 'push');
            app.MoyenneButton.ButtonPushedFcn = createCallbackFcn(app, @MoyenneButtonPushed, true);
            app.MoyenneButton.Text = 'Moyenne';
            app.AutresTab = uitab(app.TabGroup);
            app.AutresTab.Title = 'Autres';
            app.ResizeButton = uibutton(app.AutresTab, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.LUTButton = uibutton(app.AutresTab, 'push');
            app.LUTButton.ButtonPushedFcn = createCallbackFcn(app, @LUTButtonPushed, true);
            app.LUTButton.Text = 'LUT';
            app.SeuillageButton = uibutton(app.AutresTab, 'push');
            app.SeuillageButton.ButtonPushedFcn = createCallbackFcn(app, @SeuillageButtonPushed, true);
            app.SeuillageButton.Text = 'Seuillage';
            app.AutresLabel = uilabel(app.AutresTab);
            app.AutresLabel.Text = 'Autres';
            app.BaseButton = uibutton(app.AutresTab, 'push');
            app.BaseButton.ButtonPushedFcn = createCallbackFcn(app, @BaseButtonPushed, true);
            app.BaseButton.Text = 'Base';
            app.Spinner = uispinner(app.AutresTab);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner_2 = uispinner(app.AutresTab);
            app.Spinner_3 = uispinner(app.AutresTab);
            app.Spinner_4 = uispinner(app.AutresTab);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPLICATION_FILTRES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end