classdef MY_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        openfileMenu                 matlab.ui.container.Menu
        savefileMenu                 matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        ResetMenu                    matlab.ui.container.Menu
        UndoMenu                     matlab.ui.container.Menu
        RedoMenu                     matlab.ui.container.Menu
        HistoMenu                    matlab.ui.container.Menu
        Switch                       matlab.ui.control.Switch
        HISTOGRAMMELabel             matlab.ui.control.Label
        uploadyourimagetostartLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        ConvolutionTab_2             matlab.ui.container.Tab
        applyButton_2                matlab.ui.control.StateButton
        EditField_18                 matlab.ui.control.NumericEditField
        EditField_17                 matlab.ui.control.NumericEditField
        EditField_15                 matlab.ui.control.NumericEditField
        EditField_14                 matlab.ui.control.NumericEditField
        EditField_13                 matlab.ui.control.NumericEditField
        EditField_12                 matlab.ui.control.NumericEditField
        EditField_11                 matlab.ui.control.NumericEditField
        EditField_10                 matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        AddkernelLabel_2             matlab.ui.control.Label
        FiltersTab                   matlab.ui.container.Tab
        FilterfamiliesLabel          matlab.ui.control.Label
        moyenneButton                matlab.ui.control.Button
        gaussianButton               matlab.ui.control.Button
        medianButton                 matlab.ui.control.Button
        ContoursTab                  matlab.ui.container.Tab
        EdgedetectionLabel           matlab.ui.control.Label
        sobelButton                  matlab.ui.control.Button
        prewittButton                matlab.ui.control.Button
        RobertButton                 matlab.ui.control.Button
        LuminanceTab                 matlab.ui.container.Tab
        IntensityLabel               matlab.ui.control.Label
        LuminanceLabel               matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        FourierTab                   matlab.ui.container.Tab
        TransformButton              matlab.ui.control.Button
        FourierLabel                 matlab.ui.control.Label
        ApplyButton_5                matlab.ui.control.Button
        BaseTab                      matlab.ui.container.Tab
        BaseLabel                    matlab.ui.control.Label
        ApplyButton_7                matlab.ui.control.Button
        HsitogrammeTab               matlab.ui.container.Tab
        histoequalizationButton      matlab.ui.control.Button
        histogrammeButton            matlab.ui.control.Button
        RotationTab                  matlab.ui.container.Tab
        RotationLabel                matlab.ui.control.Label
        applyButton                  matlab.ui.control.StateButton
        angleLabel                   matlab.ui.control.Label
        EditField_19                 matlab.ui.control.NumericEditField
        BruitTab                     matlab.ui.container.Tab
        BruitLabel                   matlab.ui.control.Label
        ApplyButton_2                matlab.ui.control.Button
        GrisTab                      matlab.ui.container.Tab
        GrayLabel                    matlab.ui.control.Label
        ApplyButton_3                matlab.ui.control.Button
        MiroirTab                    matlab.ui.container.Tab
        MiroirLabel                  matlab.ui.control.Label
        HorizontalButton             matlab.ui.control.Button
        VerticalButton               matlab.ui.control.Button
        ErosionTab_2                 matlab.ui.container.Tab
        ErosionLabel                 matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
        DilationTab                  matlab.ui.container.Tab
        DilationLabel                matlab.ui.control.Label
        ApplyButton_4                matlab.ui.control.Button
        ResizeTab                    matlab.ui.container.Tab
        ApplyButton_6                matlab.ui.control.Button
        ResizeLabel                  matlab.ui.control.Label
        SeuillageTab                 matlab.ui.container.Tab
        SeuillageButton              matlab.ui.control.Button
        seuilLabel                   matlab.ui.control.Label
        EditField_20                 matlab.ui.control.NumericEditField
        MoreTab                      matlab.ui.container.Tab
        FactorLabel                  matlab.ui.control.Label
        EditField_21                 matlab.ui.control.NumericEditField
        MultipyButton                matlab.ui.control.Button
        SubtractionimagesLabel       matlab.ui.control.Label
        SubtractButton               matlab.ui.control.Button
        AdditionimagesLabel          matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: openfileMenu
        function openfileMenuSelected(app, event)
            
            global I ;
            global U ;
            [fileName, pathName] = uigetfile('*.jpg;*.png', 'Select Image');
            if fileName ~= 0
            % Open the selected image
                fullPath = fullfile(pathName, fileName);
                I = imread(fullPath);
                U = I ;
                % Show the image in the axes
                
                imshow(I, 'Parent', app.UIAxes);
                app.TabGroup.Visible = "on" ;
                app.ResetMenu.Visible = "on";
                app.uploadyourimagetostartLabel.Text = ""  ;
                app.Switch.Visible = "on" ;
                app.UndoMenu.Visible = "on";
                app.RedoMenu.Visible = "on" ;
                app.HistoMenu.Visible ="on" ;
                app.savefileMenu.Enable="on" ;
                
            end
       
        end

        % Button pushed function: RobertButton
        function RobertButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            f=getframe(app.UIAxes);
            im1 = f.cdata;
            U = im1 ;
            J = Robert(im1);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');


        end

        % Menu selected function: savefileMenu
        function savefileMenuSelected(app, event)
            [fileName, pathName] = uiputfile('*.jpg' ,'*.png', 'Save Image as');

            % Create full file path by concatenating pathName and fileName
            fullFilePath = fullfile(pathName, fileName);

            % Save image using imwrite function
            image = getimage(app.UIAxes) ;
            imwrite(image, fullFilePath);
        end

        % Button pushed function: prewittButton
        function prewittButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            J=im2gray(a) ; %rgb2gray(a);
            J =Prewitt(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: histogrammeButton
        function histogrammeButtonPushed(app, event)
           
            f=getframe(app.UIAxes);
            H = f.cdata;
            H=im2gray(H);
            H =histograme(H);
            app.UIAxes_2.Visible = "on" ;
            bar('Parent',app.UIAxes_2,H);
            app.HISTOGRAMMELabel.Visible = "on" ;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Read an image
             global L ;
             global U ;
             axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
             J = getimage(app.UIAxes);
             U = J ;
             se = strel('square', 3);
             I_eroded = imerode(J, se);
             L = I_eroded ;
            imshow(I_eroded,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
   

        end

        % Button pushed function: medianButton
        function medianButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            %a= medfilt2(a) ;
            %K=rgb2gray(a); 
            % K =Median(K); %K =FiltreMedian(K ,3);
            prompt = {'filter size'};
            dlgtitle = 'Median ';
            dims = [1 35];
            definput = {''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            size = str2num(answer{1}) ;
            red_channel = a(:, :, 1);
            green_channel = a(:, :, 2);
            blue_channel = a(:, :, 3);
            x = size ;
            
            red_channel = medfilt2(red_channel, [x x]);
            green_channel = medfilt2(green_channel, [x x]);
            blue_channel = medfilt2(blue_channel, [x x]);
            
            K = cat(3, red_channel, green_channel, blue_channel);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');

        end

        % Button pushed function: gaussianButton
        function gaussianButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            % K=img2gray(a);
            %K =Gaussian(K);
            %L = K ;
            %K =FiltreGaussien(K ,5, 7);
            prompt = {'filter size'};
            dlgtitle = 'Gaussian Blur';
            dims = [1 35];
            definput = {''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            size = str2num(answer{1});

            K= imgaussfilt(a,size);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: moyenneButton
        function moyenneButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            prompt = {'filter size'};
            dlgtitle = 'Gaussian Blur';
            dims = [1 35];
            definput = {''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            size = str2num(answer{1});
            K=im2gray(a);
            %K =Medium(K);%K =FiltreMoyenne(K ,5);
            x = size ;
            matrix = ones(x,x)/(x*x);
            K = imfilter(K, matrix);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Value changed function: applyButton_2
        function applyButton_2ValueChanged(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            value = app.applyButton_2.Value;
            J = getimage(app.UIAxes) ;
            U = J ;
            a1=app.EditField_9.Value; a2=app.EditField_10.Value; a3=app.EditField_11.Value;
            a4=app.EditField_12.Value; a5=app.EditField_13.Value; a6=app.EditField_14.Value;
            a7=app.EditField_15.Value; a8=app.EditField_17.Value; a9=app.EditField_18.Value;
            K=[a1 a2 a3;a4 a5 a6;a7 a8  a9];
            %J=convn(J,K);
            %J = im2gray(J);
            J = imfilter(J,K,'conv');
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Value changed function: applyButton
        function applyButtonValueChanged(app, event)
            global L ;
            global U ;
            
            value = app.applyButton.Value;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes);
            U = J;
            R=app.EditField_19.Value;
            %J =rotation(J,R);
            J = imrotate(J, R);
            L= J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            %app.UIAxes_2.Visible = "off" ;
            global L ; 
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes);
            U = J ;
            J =bruit(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes);
            U = J ;
            J =rgb2gray(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            global I ;
            
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            imshow(I,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes) ;
            U = J ;
            J =miroireH(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes);
            U = J ;
            J =miroireV(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: ApplyButton_4
        function ApplyButton_4Pushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            se = strel('square', 3); % 3x3 square structuring element
            J = imdilate(a, se);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            
        end

        % Button pushed function: histoequalizationButton
        function histoequalizationButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            a = getimage(app.UIAxes) ;
            U = a ;
            J=rgb2gray(a) ;
            J =histogramme_equalization(J);
            L = J;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Callback function
        function LuminanceButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            R=app.Slider.Value;
            im1 = getimage(app.UIAxes);
            U = im1 ;
            im1 =luminanceMoins(im1,R);
            L =im1 ;
            imshow(im1,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Callback function
        function LuminanceButton_2Pushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            R=app.Slider.Value;
            im1 = getimage(app.UIAxes);
            U = im1 ;
            im1 =luminancePlus(im1,R);
            L = im1 ;
            imshow(im1,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            global I ;
            global L ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            if strcmp(value,"On") 
                 imshow(I,'Parent',app.UIAxes);
                 axis(app.UIAxes, 'tight');
            end 
            if strcmp(value,"Off")
                 imshow(L,'Parent',app.UIAxes);
                 axis(app.UIAxes, 'tight');
            end   
        end

        % Callback function
        function IphoneFilterButtonPushed(app, event)
            
            % Read the image
           
            img = getimage(app.UIAxes);
            % Convert the image to the L*a*b color space
            cform = makecform('srgb2lab');
            lab_img = applycform(img, cform);

             % Apply the filter
            lab_img(:, :, 2) = lab_img(:, :, 2) * 1.5;
            lab_img(:, :, 3) = lab_img(:, :, 3) * 1.25;

             % Convert the image back to the RGB color space
            cform = makecform('lab2srgb');
            iphone_img = applycform(lab_img, cform);

          % Display the filtered image
           imshow(iphone_img,'Parent',app.UIAxes);
           axis(app.UIAxes, 'tight');
        end

        % Callback function
        function changelechelleButtonPushed(app, event)
            img = getimage(app.UIAxes) ;
            % Resize the image
            H = app.highEditField_3.Value ;
            W = app.highEditField_2.Value ;
            new_size = [H W];
            resized_img = imresize(img, new_size);

            % Display the resized image
            imshow(resized_img,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: ApplyButton_5
        function ApplyButton_5Pushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            [fileName, pathName] = uigetfile('*.jpg;*.png', 'Select Image');
            if fileName ~= 0
            % Open the selected image
            fullPath = fullfile(pathName, fileName);
            img1 = imread(fullPath);
            img2 = getimage(app.UIAxes);
            U = img2 ;
            imageA = img2;
            imageB = img1;
            fftA = fft2(double(imageA));
            fftB = fft2(double(imageB));
            fftC = abs(fftA).*exp(1i*angle(fftB));
            imageC = ifft2(fftC);
            L = imageC ;
            imshow(imageC,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: SeuillageButton
        function SeuillageButtonPushed(app, event)
            
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            J=getimage(app.UIAxes);
            U = J ;
            K=app.EditField_20.Value ;
            J = rgb2gray(J);
            J =seuillage(J,K);
            L = J ;
            imshow(J,'Parent',app.UIAxes);   
            axis(app.UIAxes, 'tight');
        end

        % Menu selected function: UndoMenu
        function UndoMenuSelected(app, event)
            global U ;
            imshow(U,'Parent',app.UIAxes);   
            axis(app.UIAxes, 'tight');
        end

        % Menu selected function: RedoMenu
        function RedoMenuSelected(app, event)
           global L;
            imshow(L,'Parent',app.UIAxes);   
            axis(app.UIAxes, 'tight'); 
        end

        % Callback function
        function tailleButtonPushed(app, event)
            img = getimage(app.UIAxes) ;
            RI = imresize(img,[50 100]);
            imshow(RI,'Parent',app.UIAxes);   
            axis(app.UIAxes, 'tight'); 
        end

        % Button pushed function: ApplyButton_6
        function ApplyButton_6Pushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            img = getimage(app.UIAxes) ;
            U=img ;
            RI = imresize(img, 0.5, 'Antialiasing', true);%imresize(img,0.4);%imresize(img,[1.5 NaN]);
            % Zoom factor (0.5 zooms out by a factor of 2)
           
            L = RI ;
            imshow(RI,'Parent',app.UIAxes);   
            axis(app.UIAxes, 'tight');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            value = app.Slider.Value;
            R=app.Slider.Value;
            im1 = getimage(app.UIAxes);
            U = im1 ;
            im1 =luminancePlus(im1,R);
            L = im1 ;
            imshow(im1,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            [fileName, pathName] = uigetfile('*.jpg;*.png', 'Select Image');
            if fileName ~= 0
            % Open the selected image
            fullPath = fullfile(pathName, fileName);
            img1 = imread(fullPath);
            img2 = getimage(app.UIAxes);
            U = img2 ;
            K = imadd(img2,img1);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            cla(app.UIAxes_2);
            [fileName, pathName] = uigetfile('*.jpg;*.png', 'Select Image');
            if fileName ~= 0
            % Open the selected image
            fullPath = fullfile(pathName, fileName);
            img1 = imread(fullPath);
            img2 = getimage(app.UIAxes);
            U = img2 ;
            K = imsubtract(img2,img1);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            end
        end

        % Button pushed function: MultipyButton
        function MultipyButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            img2 = getimage(app.UIAxes);
            f = app.EditField_21.Value ;
            U = img2 ;
            K = immultiply(img2,f);
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
            
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            
            image = getimage(app.UIAxes);
            U = image ;
            fourier_transform=fft2(image);
            module = abs(fftshift(fourier_transform));

            K=module/4000;
            L = K ;
            imshow(K,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Menu selected function: HistoMenu
        function HistoMenuSelected(app, event)
            f=getframe(app.UIAxes);
            H = f.cdata;
            H=im2gray(H);
            H =histograme(H);
            app.UIAxes_2.Visible = "on" ;
            bar('Parent',app.UIAxes_2,H);
            app.HISTOGRAMMELabel.Visible = "on" ;
        end

        % Button pushed function: ApplyButton_7
        function ApplyButton_7Pushed(app, event)
            
            global L ;
            global U ;
            axis(app.UIAxes_2,'off');
            app.HISTOGRAMMELabel.Visible="off";
            J=getimage(app.UIAxes);
            U = J ;
            J =base(J);
            L = J ;
            imshow(J,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end

        % Callback function: not associated with a component
        function filtreButtonPushed(app, event)
            I = getimage(app.UIAxes);
            F=fftshift(fft2(I)); %calcul de la taille de l'image; 
            M=size(F,1); 
            N=size(F,2); 
            P=size(F,3); 
            H0=zeros(M,N); D0=3; M2=round(M/2); N2=round(N/2); 
            H0(M2-D0:M2+D0,N2-D0:N2+D0)=1; 
            for i=1:M 
            for j=1:N 
            G(i,j)=F(i,j)*H0(i,j); 
             end
           end
           g=ifft2(G);
           imshow(g,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.openfileMenu = uimenu(app.FileMenu);
            app.openfileMenu.MenuSelectedFcn = createCallbackFcn(app, @openfileMenuSelected, true);
            app.openfileMenu.Text = 'openfile';
            app.savefileMenu = uimenu(app.FileMenu);
            app.savefileMenu.MenuSelectedFcn = createCallbackFcn(app, @savefileMenuSelected, true);
            app.savefileMenu.Enable = 'off';
            app.savefileMenu.Text = 'savefile';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.ResetMenu = uimenu(app.UIFigure);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Visible = 'off';
            app.ResetMenu.Text = 'Reset';
            app.UndoMenu = uimenu(app.UIFigure);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoMenuSelected, true);
            app.UndoMenu.Visible = 'off';
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.UIFigure);
            app.RedoMenu.MenuSelectedFcn = createCallbackFcn(app, @RedoMenuSelected, true);
            app.RedoMenu.Visible = 'off';
            app.RedoMenu.Text = 'Redo';
            app.HistoMenu = uimenu(app.UIFigure);
            app.HistoMenu.MenuSelectedFcn = createCallbackFcn(app, @HistoMenuSelected, true);
            app.HistoMenu.Visible = 'off';
            app.HistoMenu.Text = 'Histo';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, {''; ''})
            app.UIAxes.ALim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, {''; ''})
            app.UIAxes_2.ALim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.ConvolutionTab_2 = uitab(app.TabGroup);
            app.ConvolutionTab_2.Title = 'Convolution';
            app.AddkernelLabel_2 = uilabel(app.ConvolutionTab_2);
            app.AddkernelLabel_2.Text = 'Add kernel :';
            app.EditField_9 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_10 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_11 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_12 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_13 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_14 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_15 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_17 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.EditField_18 = uieditfield(app.ConvolutionTab_2, 'numeric');
            app.applyButton_2 = uibutton(app.ConvolutionTab_2, 'state');
            app.applyButton_2.ValueChangedFcn = createCallbackFcn(app, @applyButton_2ValueChanged, true);
            app.applyButton_2.Text = 'apply';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.medianButton = uibutton(app.FiltersTab, 'push');
            app.medianButton.ButtonPushedFcn = createCallbackFcn(app, @medianButtonPushed, true);
            app.medianButton.Text = 'median';
            app.gaussianButton = uibutton(app.FiltersTab, 'push');
            app.gaussianButton.ButtonPushedFcn = createCallbackFcn(app, @gaussianButtonPushed, true);
            app.gaussianButton.Text = 'gaussian';
            app.moyenneButton = uibutton(app.FiltersTab, 'push');
            app.moyenneButton.ButtonPushedFcn = createCallbackFcn(app, @moyenneButtonPushed, true);
            app.moyenneButton.Text = 'moyenne';
            app.FilterfamiliesLabel = uilabel(app.FiltersTab);
            app.FilterfamiliesLabel.Text = 'Filter families';
            app.ContoursTab = uitab(app.TabGroup);
            app.ContoursTab.Title = 'Contours';
            app.RobertButton = uibutton(app.ContoursTab, 'push');
            app.RobertButton.ButtonPushedFcn = createCallbackFcn(app, @RobertButtonPushed, true);
            app.RobertButton.Text = 'Robert';
            app.prewittButton = uibutton(app.ContoursTab, 'push');
            app.prewittButton.ButtonPushedFcn = createCallbackFcn(app, @prewittButtonPushed, true);
            app.prewittButton.Text = 'prewitt';
            app.sobelButton = uibutton(app.ContoursTab, 'push');
            app.sobelButton.Text = 'sobel';
            app.EdgedetectionLabel = uilabel(app.ContoursTab);
            app.EdgedetectionLabel.Text = 'Edge detection';
            app.LuminanceTab = uitab(app.TabGroup);
            app.LuminanceTab.Title = 'Luminance';
            app.Slider = uislider(app.LuminanceTab);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 10;
            app.LuminanceLabel = uilabel(app.LuminanceTab);
            app.LuminanceLabel.Text = 'Luminance ';
            app.IntensityLabel = uilabel(app.LuminanceTab);
            app.IntensityLabel.Text = 'Intensity :';
            app.FourierTab = uitab(app.TabGroup);
            app.FourierTab.Title = 'Fourier';
            app.ApplyButton_5 = uibutton(app.FourierTab, 'push');
            app.ApplyButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_5Pushed, true);
            app.ApplyButton_5.Text = 'Apply';
            app.FourierLabel = uilabel(app.FourierTab);
            app.FourierLabel.Text = 'Fourier:';
            app.TransformButton = uibutton(app.FourierTab, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform ';
            app.BaseTab = uitab(app.TabGroup);
            app.BaseTab.Title = 'Base';
            app.ApplyButton_7 = uibutton(app.BaseTab, 'push');
            app.ApplyButton_7.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_7Pushed, true);
            app.ApplyButton_7.Text = 'Apply';
            app.BaseLabel = uilabel(app.BaseTab);
            app.BaseLabel.Text = 'Base :';
            app.HsitogrammeTab = uitab(app.TabGroup);
            app.HsitogrammeTab.Title = 'Hsitogramme';
            app.histogrammeButton = uibutton(app.HsitogrammeTab, 'push');
            app.histogrammeButton.ButtonPushedFcn = createCallbackFcn(app, @histogrammeButtonPushed, true);
            app.histogrammeButton.Text = 'histogramme';
            app.histoequalizationButton = uibutton(app.HsitogrammeTab, 'push');
            app.histoequalizationButton.ButtonPushedFcn = createCallbackFcn(app, @histoequalizationButtonPushed, true);
            app.histoequalizationButton.Text = 'histo equalization';
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.EditField_19 = uieditfield(app.RotationTab, 'numeric');
            app.angleLabel = uilabel(app.RotationTab);
            app.angleLabel.Text = 'angle : ';
            app.applyButton = uibutton(app.RotationTab, 'state');
            app.applyButton.ValueChangedFcn = createCallbackFcn(app, @applyButtonValueChanged, true);
            app.applyButton.Text = 'apply';
            app.RotationLabel = uilabel(app.RotationTab);
            app.RotationLabel.Text = 'Rotation :';
            app.BruitTab = uitab(app.TabGroup);
            app.BruitTab.Title = 'Bruit';
            app.ApplyButton_2 = uibutton(app.BruitTab, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.BruitLabel = uilabel(app.BruitTab);
            app.BruitLabel.Text = 'Bruit :';
            app.GrisTab = uitab(app.TabGroup);
            app.GrisTab.Title = 'Gris';
            app.ApplyButton_3 = uibutton(app.GrisTab, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.GrayLabel = uilabel(app.GrisTab);
            app.GrayLabel.Text = 'Gray :';
            app.MiroirTab = uitab(app.TabGroup);
            app.MiroirTab.Title = 'Miroir';
            app.VerticalButton = uibutton(app.MiroirTab, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.HorizontalButton = uibutton(app.MiroirTab, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.MiroirLabel = uilabel(app.MiroirTab);
            app.MiroirLabel.Text = 'Miroir :';
            app.ErosionTab_2 = uitab(app.TabGroup);
            app.ErosionTab_2.Title = 'Erosion';
            app.ApplyButton = uibutton(app.ErosionTab_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ErosionLabel = uilabel(app.ErosionTab_2);
            app.ErosionLabel.Text = 'Erosion :';
            app.DilationTab = uitab(app.TabGroup);
            app.DilationTab.Title = 'Dilation';
            app.ApplyButton_4 = uibutton(app.DilationTab, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_4Pushed, true);
            app.ApplyButton_4.Text = 'Apply';
            app.DilationLabel = uilabel(app.DilationTab);
            app.DilationLabel.Text = 'Dilation :';
            app.ResizeTab = uitab(app.TabGroup);
            app.ResizeTab.Title = 'Resize';
            app.ResizeLabel = uilabel(app.ResizeTab);
            app.ResizeLabel.Text = 'Resize ;';
            app.ApplyButton_6 = uibutton(app.ResizeTab, 'push');
            app.ApplyButton_6.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_6Pushed, true);
            app.ApplyButton_6.Text = 'Apply';
            app.SeuillageTab = uitab(app.TabGroup);
            app.SeuillageTab.Title = 'Seuillage';
            app.EditField_20 = uieditfield(app.SeuillageTab, 'numeric');
            app.seuilLabel = uilabel(app.SeuillageTab);
            app.seuilLabel.Text = 'seuil :';
            app.SeuillageButton = uibutton(app.SeuillageTab, 'push');
            app.SeuillageButton.ButtonPushedFcn = createCallbackFcn(app, @SeuillageButtonPushed, true);
            app.SeuillageButton.Text = 'Seuillage';
            app.MoreTab = uitab(app.TabGroup);
            app.MoreTab.Title = 'More ..';
            app.AddButton = uibutton(app.MoreTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AdditionimagesLabel = uilabel(app.MoreTab);
            app.AdditionimagesLabel.Text = 'Addition images';
            app.SubtractButton = uibutton(app.MoreTab, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.SubtractionimagesLabel = uilabel(app.MoreTab);
            app.SubtractionimagesLabel.Text = 'Subtraction images';
            app.MultipyButton = uibutton(app.MoreTab, 'push');
            app.MultipyButton.ButtonPushedFcn = createCallbackFcn(app, @MultipyButtonPushed, true);
            app.MultipyButton.Text = 'Multipy';
            app.EditField_21 = uieditfield(app.MoreTab, 'numeric');
            app.FactorLabel = uilabel(app.MoreTab);
            app.FactorLabel.Text = 'Factor :';
            app.uploadyourimagetostartLabel = uilabel(app.UIFigure);
            app.uploadyourimagetostartLabel.Text = 'upload your image to start ...';
            app.HISTOGRAMMELabel = uilabel(app.UIFigure);
            app.HISTOGRAMMELabel.Visible = 'off';
            app.HISTOGRAMMELabel.Text = 'HISTOGRAMME : ';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MY_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end